classdef FixMasksBatch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoadBatchObjectsButton  matlab.ui.control.Button
        SubtractFromMaskButton  matlab.ui.control.Button
        AddtoMaskButton         matlab.ui.control.Button
        EditMaskButton          matlab.ui.control.Button
        AcceptChangesButton     matlab.ui.control.Button
        LoadObjectsButton       matlab.ui.control.Button
        ObjectsSlider           matlab.ui.control.Slider
        ObjectsSliderLabel      matlab.ui.control.Label
        ImageAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ObjectsCell % Cell the holds the information about each object, its mask and its original image. 
        CurObject % Integer to hold current object number
        OutputFolder % Folder where storage array originated
        BatchObjectsCell % Cell array holding all objects from data set
        BatchProcessing % Flag to indicate batch processing to handle new data inputs. 
    end
    
    methods (Access = private)
        
        function showCurImage(app)

            if app.BatchProcessing == 0
            
                %Show current object and allow for edits
                curMask = app.ObjectsCell.store(app.CurObject.Value);
                curMask = curMask{1}{2};
                curImage = app.ObjectsCell.store(app.CurObject.Value);
                curImage = curImage{1}{1};
                
                imshow(imfuse(curImage, curMask), 'Parent', app.ImageAxes)
                app.ImageAxes.Title.String = ['Object ' num2str(app.CurObject.Value)];
            elseif app.BatchProcessing == 1

                %Show current object and allow for edits
                app.CurObject.Value = app.CurObject.Value + 1;

                curMask = app.BatchObjectsCell.fullStoreCell(app.CurObject.Value);
                curMask = curMask{1}{2};
                curImage = app.BatchObjectsCell.fullStoreCell(app.CurObject.Value);
                curImage = curImage{1}{1};
                
                imshow(imfuse(curImage, curMask), 'Parent', app.ImageAxes)
                app.ImageAxes.Title.String = ['Object ' num2str(app.CurObject.Value)];

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ObjectsSlider.Value = 1;
            app.CurObject.Value = 1;
            app.BatchProcessing = 0;
        end

        % Button pushed function: LoadObjectsButton
        function LoadObjectsButtonPushed(app, event)
            [file, path] = uigetfile;
            drawnow;
            figure(app.UIFigure)
            
            app.OutputFolder = path;
            app.CurObject.Value = 1;
            
            app.ObjectsCell = load(fullfile(path, file));
           
            numObjects = height(app.ObjectsCell.store);
                     
            %Change bounds on the slider to the size of the number of
            %objects
            if numObjects == 1
                app.ObjectsSlider.Limits = [1, 1.1];
            else
                app.ObjectsSlider.Limits = [1, numObjects];
            end
            
            showCurImage(app)
            
        end

        % Value changing function: ObjectsSlider
        function ObjectsSliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.CurObject.Value = round(changingValue);
            
            showCurImage(app)
        end

        % Button pushed function: EditMaskButton
        function EditMaskButtonPushed(app, event)
            %ax = axes('Parent', app.ImageAxes);
            fix = imfreehand(app.ImageAxes);
            mask = fix.createMask;
            
            %Now save masks to the original array and replot the image
            if app.BatchProcessing == 0
                app.ObjectsCell.store{app.CurObject.Value}{2} = mask;
            elseif app.BatchProcessing == 1
                app.BatchObjectsCell.fullStoreCell{app.CurObject.Value}{2} = mask;
                app.CurObject.Value = app.CurObject.Value - 1;
            end
            
            showCurImage(app)
        end

        % Button pushed function: AcceptChangesButton
        function AcceptChangesButtonPushed(app, event)
            %Edit storage array and recalculate appropriate values. 
            if app.BatchProcessing == 0
                store = app.ObjectsCell.store;
                pxlSize = store{1,4};
                OutputArray = zeros(height(store), 13);
                %Loop through each object and recompute intensity values
                for i = 1:height(store)
                    disp(['Curent Object_' num2str(i)])
                   curImage =  store{i,1}{1};
                   curMask = store{i,1}{2};
                   
                   rawIntDen = sum(curImage(curMask));
                   IntDen = rawIntDen * pxlSize;
                   
                   regProps = regionprops(curMask, 'all');
                    
                   area = regProps.Area;
                           
                   aspectRatio = regProps.MajorAxisLength/regProps.MinorAxisLength;
                   
                   store{i,2} = rawIntDen;
                   store{i,3} = IntDen;
                   nemOut = calculate_order_parameter(curImage);
                   
                   OutputArray(i,1) = i;
                   OutputArray(i,2) = rawIntDen;
                   OutputArray(i,3) = IntDen;
                   OutputArray(i,4) = pxlSize; 
                   OutputArray(i,5) = area*pxlSize;
                   OutputArray(i,6) = aspectRatio; 
                   OutputArray(i,7) = regProps.Circularity;
                   OutputArray(i,8) = regProps.Eccentricity;
                   OutputArray(i,9) = regProps.MajorAxisLength;
                   OutputArray(i,10) = regProps.MinorAxisLength;
                   OutputArray(i,11) = regProps.Orientation;
                   OutputArray(i,12) = nemOut.S;
                   OutputArray(i,13) = nemOut.orientation;
                   
                   
                   %Write objects to the folder
                   overlay = imfuse(curImage, curMask);
                   imwrite(overlay, fullfile(app.OutputFolder, ['Object', num2str(i),'.png']))
                   
                end
    
    
                save(fullfile(app.OutputFolder, 'StorageArray.mat'), 'store')
                xlswrite(fullfile(app.OutputFolder, 'DataOutput.xls'),OutputArray, 'Sheet1','A2');
            elseif app.BatchProcessing == 1
                store = app.BatchObjectsCell.fullStoreCell;
                pxlSize = store{2,4};
                OutputArray = zeros(height(store), 13);
                %Loop through each object and recompute intensity values
                for i = 2:height(store)
                    disp(['Curent Object_' num2str(i)])
                   curImage =  store{i,1}{1};
                   curMask = store{i,1}{2};
                   
                   rawIntDen = sum(curImage(curMask));
                   IntDen = rawIntDen * pxlSize;
                   
                   regProps = regionprops(curMask, 'all');
                    
                   area = regProps.Area;
                           
                   aspectRatio = regProps.MajorAxisLength/regProps.MinorAxisLength;
                   
                   store{i,2} = rawIntDen;
                   store{i,3} = IntDen;
                   nemOut = calculate_order_parameter(curImage);
                   
                   OutputArray(i,1) = i;
                   OutputArray(i,2) = rawIntDen;
                   OutputArray(i,3) = IntDen;
                   OutputArray(i,4) = pxlSize; 
                   OutputArray(i,5) = area*pxlSize;
                   OutputArray(i,6) = aspectRatio; 
                   OutputArray(i,7) = regProps.Circularity;
                   OutputArray(i,8) = regProps.Eccentricity;
                   OutputArray(i,9) = regProps.MajorAxisLength;
                   OutputArray(i,10) = regProps.MinorAxisLength;
                   OutputArray(i,11) = regProps.Orientation;
                   OutputArray(i,12) = nemOut.S;
                   OutputArray(i,13) = nemOut.orientation;
                   
                   
                   %Write objects to the folder
                   overlay = imfuse(curImage, curMask);
                   imwrite(overlay, fullfile(app.OutputFolder, ['Object', num2str(i),'.png']))
                   
                end
    
    
                save(fullfile(app.OutputFolder, 'CompiledObjects.mat'), 'store')
                xlswrite(fullfile(app.OutputFolder, 'CompiledObjects.xls'),OutputArray, 'Sheet1','A2');

            end

            disp('Progress Saved')
            
            
        end

        % Button pushed function: AddtoMaskButton
        function AddtoMaskButtonPushed(app, event)
            fix = imfreehand(app.ImageAxes);
            mask = fix.createMask;
            
            %add this to the existing mask
            if app.BatchProcessing == 0
                curMask = app.ObjectsCell.store{app.CurObject.Value}{2};
                curMask = or(curMask, mask);
            
                app.ObjectsCell.store{app.CurObject.Value}{2} = curMask;
            elseif app.BatchProcessing == 1
                curMask = app.BatchObjectsCell.fullStoreCell{app.CurObject.Value}{2};
                curMask = or(curMask, mask);
            
                app.BatchObjectsCell.fullStoreCell{app.CurObject.Value}{2} = curMask;
                app.CurObject.Value = app.CurObject.Value - 1;
            end

            showCurImage(app)
            
        end

        % Button pushed function: SubtractFromMaskButton
        function SubtractFromMaskButtonPushed(app, event)
            fix = imfreehand(app.ImageAxes);
            mask = fix.createMask;
            
            %Subtract this from the existing mask
            if app.BatchProcessing == 0
                curMask = app.ObjectsCell.store{app.CurObject.Value}{2};
                curMask = curMask - mask;
                %Need to set any values less than 0 to zero, and greater than 1
                %to 1
                curMask(curMask<0) = 0;
                curMask(curMask>1) = 1;
                curMask = logical(curMask);
                
                app.ObjectsCell.store{app.CurObject.Value}{2} = curMask;
            elseif app.BatchProcessing == 1
                curMask = app.BatchObjectsCell.fullStoreCell{app.CurObject.Value}{2};
                curMask = curMask - mask;
                %Need to set any values less than 0 to zero, and greater than 1
                %to 1
                curMask(curMask<0) = 0;
                curMask(curMask>1) = 1;
                curMask = logical(curMask);
                
                app.BatchObjectsCell.fullStoreCell{app.CurObject.Value}{2} = curMask;
                app.CurObject.Value = app.CurObject.Value - 1;
            end
            showCurImage(app)
        end

        % Button pushed function: LoadBatchObjectsButton
        function LoadBatchObjectsButtonPushed(app, event)
            [file, path] = uigetfile;
            drawnow;
            figure(app.UIFigure)

            app.BatchProcessing = 1;
          
            app.OutputFolder = path;
            app.CurObject.Value = 1;
            
            app.BatchObjectsCell = load(fullfile(path, file));
           
            numObjects = height(app.BatchObjectsCell.fullStoreCell);
                     
            %Change bounds on the slider to the size of the number of
            %objects
            if numObjects == 1
                app.ObjectsSlider.Limits = [1, 1.1];
            else
                app.ObjectsSlider.Limits = [1, numObjects-1];
            end
            
            showCurImage(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Title')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            app.ObjectsSliderLabel = uilabel(app.UIFigure);
            app.ObjectsSliderLabel.Text = 'Objects';
            app.ObjectsSlider = uislider(app.UIFigure);
            app.ObjectsSlider.ValueChangingFcn = createCallbackFcn(app, @ObjectsSliderValueChanging, true);
            app.LoadObjectsButton = uibutton(app.UIFigure, 'push');
            app.LoadObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadObjectsButtonPushed, true);
            app.LoadObjectsButton.Text = 'Load Objects';
            app.AcceptChangesButton = uibutton(app.UIFigure, 'push');
            app.AcceptChangesButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptChangesButtonPushed, true);
            app.AcceptChangesButton.Text = 'Accept Changes';
            app.EditMaskButton = uibutton(app.UIFigure, 'push');
            app.EditMaskButton.ButtonPushedFcn = createCallbackFcn(app, @EditMaskButtonPushed, true);
            app.EditMaskButton.Text = 'Edit Mask';
            app.AddtoMaskButton = uibutton(app.UIFigure, 'push');
            app.AddtoMaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoMaskButtonPushed, true);
            app.AddtoMaskButton.Text = 'Add to Mask';
            app.SubtractFromMaskButton = uibutton(app.UIFigure, 'push');
            app.SubtractFromMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractFromMaskButtonPushed, true);
            app.SubtractFromMaskButton.Text = 'Subtract From Mask';
            app.LoadBatchObjectsButton = uibutton(app.UIFigure, 'push');
            app.LoadBatchObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBatchObjectsButtonPushed, true);
            app.LoadBatchObjectsButton.Text = 'Load Batch Objects';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FixMasksBatch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end