classdef SegmentObjects < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        FudgeFactorSliderLabel       matlab.ui.control.Label
        FudgeFactorSlider            matlab.ui.control.Slider
        MinSizeEditFieldLabel        matlab.ui.control.Label
        MinSizeEditField             matlab.ui.control.NumericEditField
        MaxSizeEditFieldLabel        matlab.ui.control.Label
        MaxSizeEditField             matlab.ui.control.NumericEditField
        FinishedButton               matlab.ui.control.Button
        DoneCheckBox                 matlab.ui.control.CheckBox
        PixelSizeum2EditFieldLabel   matlab.ui.control.Label
        PixelSizeum2EditField        matlab.ui.control.NumericEditField
        SelectOutputDirectoryButton  matlab.ui.control.Button
        SelectSampleFoldermultiimageButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        sampleimage % Sample image file address
        fudgefactor % fudgeFactor
        mincellsize % minimum cell size
        maxcellsize % max cell size
        directory % path to folder containing images to mask
        numberFrames % Number of Frames
        currentFrame % The current frame the slider is on to mask
        images % cell array to hold the image locations to read dynamically. 
        EdgeElimnumber % amount of pixels to shrink the mask in by, 0 by default for none
        
        outputFold % Folder where the output images and files are stored
        objectStats % Regionprops for each object in the image
        pixlSize % Size of the Pixel in microns squared
        inputFolder % Folder from which an input of many images can come from
        imageStore % Cell array to hold images from multifile
        imageBWStore % Cell array to store binary masks
        fileNames % Cell array to hold the file names for each image
    end
    
    methods (Access = private)
        
        function segmentImage(app)
           
            %Do the segmentation in a loop for each image but only need to
            %display the first?
            app.imageBWStore = cell(1,numel(app.imageStore));
            
            for i = 1:numel(app.imageStore)
                
                I = app.imageStore{i};
                %display(I)
               
                [~,threshold] = edge(I,'sobel');
                fudgeFactor = app.fudgefactor.Value;
                BWs = edge(I,'sobel',threshold * fudgeFactor);
                
                %imshow(BWs)
                %title('Binary Gradient Mask')
                
                se90 = strel('line',2,90);
                se0 = strel('line',2,0);
                
                BWsdil = imdilate(BWs,[se90 se0]);
                %imshow(BWsdil)
                %title('Dilated Gradient Mask')
                
                % BWserode = imerode(BWsdil, [se90 se0]);
                % imshow(BWserode)
                % title('Eroded Gradient Mask')
                % BWsdil = BWserode;
                
                BWdfill = imfill(BWsdil, 'holes');
                %imshow(BWdfill)
                %title('Binary Image with Filled Holes')
                % 
                BWserode = imerode(BWsdil, [se90 se0]);
                %imshow(BWserode)
                %title('Eroded Gradient Mask')
                
                
                % BWnobord = imclearborder(BWdfill,4);
                % imshow(BWnobord)
                % title('Cleared Border Image')
                BWnobord = BWdfill;
                
                seD = strel('diamond',1);
                BWfinal = imerode(BWnobord,seD);
                BWfinal = imerode(BWfinal,seD);
                %imshow(BWfinal)
                %title('Segmented Image');
                %display(app.mincellsize.Value)
                %display(app.maxcellsize.Value)
                BWfinal = bwareafilt(BWfinal, [app.mincellsize.Value, app.maxcellsize.Value]);
                
                app.imageBWStore{i} = BWfinal;
                %Get props
                app.objectStats{i} = regionprops(BWfinal,'all' );
                
            end
            
            %Display the final image but store everthing else
            imshow(labeloverlay(app.imageStore{1},app.imageBWStore{1}),'Parent', app.UIAxes)
            title('Mask Over Original Image', 'Parent', app.UIAxes)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Intialize some values 
            %Pre initializing does not help this. 
            app.fudgefactor.Value = 0.5; % fudgeFactor
            app.mincellsize.Value = 1000; % minimum cell size
            app.maxcellsize.Value = 10000000; % max cell size
%             app.currentFrame.Value = 0; % The current frame the slider is on to mask
            app.EdgeElimnumber.Value = 10; % amount of pixels to shrink the mask in by, 0 by default for none
            app.pixlSize = 0.441;
            app.PixelSizeum2EditField.Value = 0.441;
            
            app.mincellsize.Value = 1000;
            app.maxcellsize.Value = 10000000;
            app.fudgefactor.Value = 0.5; % fudgeFactor
            app.EdgeElimnumber.Value = 10; % amount of pixels to shrink the mask in by, 0 by default for none
        end

        % Callback function
        function SelectSampleImageButtonPushed(app, event)
           %Get user inputed image 
           [baseName, folder] = uigetfile;
           fullfilename = fullfile(folder,baseName);
           app.sampleimage.Value = fullfilename;
        end

        % Value changing function: FudgeFactorSlider
        function FudgeFactorSliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.fudgefactor.Value = changingValue;
            %disp(app.fudgefactor.Value)            
%             cellsize = [app.mincellsize.Value, app.maxcellsize.Value];
            %disp(cellsize)
            app.segmentImage()
            %disp(app.fudgefactor.Value)
%             [maskedimage, ~] = appImageThresholdingv2(app.sampleimage.Value,app.fudgefactor.Value, cellsize,app.EdgeElimnumber.Value);
%             imshow(maskedimage,'Parent',app.UIAxes);
        end

        % Value changed function: MinSizeEditField
        function MinSizeEditFieldValueChanged(app, event)
            value = app.MinSizeEditField.Value;
            app.mincellsize.Value = value;
            app.segmentImage()
        end

        % Value changed function: MaxSizeEditField
        function MaxSizeEditFieldValueChanged(app, event)
            value = app.MaxSizeEditField.Value;
            app.maxcellsize.Value = value;
            app.segmentImage()
        end

        % Callback function
        function SelectSampleImageButtonPushed2(app, event)
            [Filename, Pathname] = uigetfile('*.*');
            app.directory.Value = fullfile(Pathname, Filename);
            %display(app.directory.Value)
            
            image = bfopen(fullfile(Pathname, Filename));
            app.images.value = image{1}{1};
            
            app.segmentImage()
            %files=dir([app.directory.Value '/*.tif']);
            %app.numberFrames.Value=size(files,1);
            %disp(app.numberFrames.Value)
            %set(app.FrameSlider, 'Limits', [0 app.numberFrames.Value-1]);
            %disp('hi')
            %names = files(6).name;
            %disp(names)
%             app.images.Value = cell(numel(app.numberFrames.Value),1);
%             
%             for i = 1:(app.numberFrames.Value)
%                 
%                 currentfilepath = strcat(files(i).folder, '\', files(i).name);
%                 
%                 app.images.Value{i} = currentfilepath;
%                 
%             end
            
            %Pre initializing does not help this. 
            app.fudgefactor.Value = 0.5; % fudgeFactor
            app.mincellsize.Value = 1000; % minimum cell size
            app.maxcellsize.Value = 10000000; % max cell size
%             app.currentFrame.Value = 0; % The current frame the slider is on to mask
            app.EdgeElimnumber.Value = 10; % amount of pixels to shrink the mask in by, 0 by default for none
            app.pixlSize = 0.441;
            app.PixelSizeum2EditField.Value = 0.441;
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            %Take the image and save the output stats to the folder
            
            %set checkbox to value = 0 to let you know its not done
            app.DoneCheckBox.Value = 0;
            
            %Wrap this all in a loop to do a bunch of files at once
            
            for j = 1:numel(app.objectStats)
                
                stats = app.objectStats{j};
            
                I = app.imageStore{j};
                %display(stats)
                outputfold = app.outputFold;
                
                %We should also grab the filename and store that while we
                %are at it to keep track of where the data is coming from
                filename = app.fileNames{j};
                
                store = cell(numel(stats),6);
                %pxlSize = 0.441;
                outputArray = zeros(numel(stats),6);
                
                for i = 1:numel(stats)
                    box = stats(i).BoundingBox;
                    
                    xMin = ceil(box(1));
                    xMax = xMin + box(3) - 1;
                    yMin = ceil(box(2));
                    yMax = yMin + box(4) - 1;
                    
                    originalObject = I(yMin:yMax, xMin:xMax);
                    objectMask = stats(i).Image;
                    overlay = imfuse(originalObject,objectMask);
                    
                    %make directory for objects
                    mkdir([outputfold,'\',filename])
                 
                    imwrite(overlay, fullfile([outputfold,'\',filename],...
                        ['Object', num2str(i),'.png']))
                    %Store objects and masks to a csv that can be opened in later steps to
                    %edit. Want to be able to pull up mask and edit it to fix errors in
                    %segmentation, then redo the calculations for the density. 
                    out = {originalObject, objectMask};
                    
                    store{i,1} = out;
                    
                    %Compute integrated intensity and raw integrated intensity
                    
                    rawIntDen = sum(originalObject(objectMask));
                    
                    IntDen = rawIntDen * app.pixlSize;
                    
                    %Compute area and aspect ratio, really grab all of the
                    %needed region props and pull out what we want:
                    
                    regProps = regionprops(objectMask, 'all');
                    
                    area = regProps.Area;
                           
                    aspectRatio = regProps.MajorAxisLength/regProps.MinorAxisLength;
                    
                    store{i,2} = rawIntDen;
                    store{i,3} = IntDen;
                    store{i,4} = app.pixlSize;
                    store{i,5} = area*app.pixlSize;
                    store{i,6} = aspectRatio;
                    
                    outputArray(i,1) = i;
                
                    outputArray(i,2) = rawIntDen;
                
                    outputArray(i,3) = IntDen;
                    
                    outputArray(i,4) = app.pixlSize;
                    
                    outputArray(i,5) = area*app.pixlSize;
                    
                    outputArray(i,6) = aspectRatio;
                  
                end
                
                save(fullfile([outputfold,'\',filename], 'StorageArray.mat'),'store')
                
                %Save the files
                
                %Add header to xlswrite
                col_header = {'Object', 'rawIntegratedDensity', 'IntegratedDensity', ...
                    'PixelSize [um^2]', 'Area [um^2]', 'AspectRatio'};
                
                xlswrite(fullfile([outputfold,'\',filename], 'DataOutput.xls'),outputArray, 'Sheet1', 'A2');
                
                xlswrite(fullfile([outputfold,'\',filename], 'DataOutput.xls'), col_header, 'Sheet1', 'A1');
            end
%             stats = app.objectStats;
%             
%             I = app.images.value;
%             %display(stats)
%             outputfold = app.outputFold;
%             
%             store = cell(numel(stats),6);
%             %pxlSize = 0.441;
%             outputArray = zeros(numel(stats),6);
%             
%             for i = 1:numel(stats)
%                 box = stats(i).BoundingBox;
%                 
%                 xMin = ceil(box(1));
%                 xMax = xMin + box(3) - 1;
%                 yMin = ceil(box(2));
%                 yMax = yMin + box(4) - 1;
%                 
%                 originalObject = I(yMin:yMax, xMin:xMax);
%                 objectMask = stats(i).Image;
%                 overlay = imfuse(originalObject,objectMask);
%                 
%                 imwrite(overlay, fullfile(outputfold,...
%                     ['Object', num2str(i),'.png']))
%                 %Store objects and masks to a csv that can be opened in later steps to
%                 %edit. Want to be able to pull up mask and edit it to fix errors in
%                 %segmentation, then redo the calculations for the density. 
%                 out = {originalObject, objectMask};
%                 
%                 store{i,1} = out;
%                 
%                 %Compute integrated intensity and raw integrated intensity
%                 
%                 rawIntDen = sum(originalObject(objectMask));
%                 
%                 IntDen = rawIntDen * app.pixlSize;
%                 
%                 %Compute area and aspect ratio, really grab all of the
%                 %needed region props and pull out what we want:
%                 
%                 regProps = regionprops(objectMask, 'all');
%                 
%                 area = regProps.Area;
%                        
%                 aspectRatio = regProps.MajorAxisLength/regProps.MinorAxisLength;
%                 
%                 store{i,2} = rawIntDen;
%                 store{i,3} = IntDen;
%                 store{i,4} = app.pixlSize;
%                 store{i,5} = area*app.pixlSize;
%                 store{i,6} = aspectRatio;
%                 
%                 outputArray(i,1) = i;
%             
%                 outputArray(i,2) = rawIntDen;
%             
%                 outputArray(i,3) = IntDen;
%                 
%                 outputArray(i,4) = app.pixlSize;
%                 
%                 outputArray(i,5) = area*app.pixlSize;
%                 
%                 outputArray(i,6) = aspectRatio;
%               
%             end
%             
%             save(fullfile(outputfold, 'StorageArray.mat'),'store')
%             
%             %Save the files
%             
%             %Add header to xlswrite
%             col_header = {'Object', 'rawIntegratedDensity', 'IntegratedDensity', ...
%                 'PixelSize [um^2]', 'Area [um^2]', 'AspectRatio'};
%             
%             xlswrite(fullfile(outputfold, 'DataOutput.xls'),outputArray, 'Sheet1', 'A2');
%             
%             xlswrite(fullfile(outputfold, 'DataOutput.xls'), col_header, 'Sheet1', 'A1');
%             

            
            %when done, set checkbox to = 1
            app.DoneCheckBox.Value = 1;
        end

        % Value changed function: PixelSizeum2EditField
        function PixelSizeum2EditFieldValueChanged(app, event)
            value = app.PixelSizeum2EditField.Value;
            app.EdgeElimnumber.Value = value;
            
            app.pixlSize = value;
        end

        % Button pushed function: SelectOutputDirectoryButton
        function SelectOutputDirectoryButtonPushed(app, event)
            outFold = uigetdir;
            app.outputFold = outFold;
        end

        % Button pushed function: SelectSampleFoldermultiimageButton
        function SelectSampleFoldermultiimageButtonPushed(app, event)
            infold = uigetdir;
            app.inputFolder = infold;
            
            %Get all images in folder and put into cell array
            files = dir([infold, '\*.czi']);
            imageStore = cell(1,numel(files));
            
            %setup object stats
            app.objectStats = cell(1, numel(files));
            
            %store file names
            app.fileNames = cell(1, numel(files));
            
            for i = 1:numel(files)
                
                Pathname = files(i).folder;
                Filename = files(i).name;
                %store file names
                app.fileNames{i} = Filename;
                
                image = bfopen(fullfile(Pathname, Filename));
                imageStore{i} = image{1}{1};
            end
            
%             image = bfopen(fullfile(Pathname, Filename));
             app.imageStore = imageStore;
            
             app.segmentImage()


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FudgeFactorSliderLabel = uilabel(app.UIFigure);
            app.FudgeFactorSliderLabel.Text = 'Fudge Factor';
            app.FudgeFactorSlider = uislider(app.UIFigure);
            app.FudgeFactorSlider.Limits = [0 1];
            app.FudgeFactorSlider.ValueChangingFcn = createCallbackFcn(app, @FudgeFactorSliderValueChanging, true);
            app.MinSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MinSizeEditFieldLabel.Text = 'Min Size';
            app.MinSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinSizeEditFieldValueChanged, true);
            app.MaxSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxSizeEditFieldLabel.Text = 'Max Size';
            app.MaxSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxSizeEditFieldValueChanged, true);
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.DoneCheckBox = uicheckbox(app.UIFigure);
            app.DoneCheckBox.Text = 'Done';
            app.PixelSizeum2EditFieldLabel = uilabel(app.UIFigure);
            app.PixelSizeum2EditFieldLabel.Text = 'Pixel Size (um^2)';
            app.PixelSizeum2EditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelSizeum2EditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizeum2EditFieldValueChanged, true);
            app.SelectOutputDirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectOutputDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputDirectoryButtonPushed, true);
            app.SelectOutputDirectoryButton.Text = 'Select Output Directory';
            app.SelectSampleFoldermultiimageButton = uibutton(app.UIFigure, 'push');
            app.SelectSampleFoldermultiimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSampleFoldermultiimageButtonPushed, true);
            app.SelectSampleFoldermultiimageButton.Text = 'Select Sample Folder (multi-image)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentObjects

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end